classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        bwareaopenLabel_2    matlab.ui.control.Label
        ThresholdingLabel_6  matlab.ui.control.Label
        MedianfilterLabel    matlab.ui.control.Label
        PreprocessedLabel    matlab.ui.control.Label
        bwareaopenLabel      matlab.ui.control.Label
        imfillLabel          matlab.ui.control.Label
        ThresholdingLabel    matlab.ui.control.Label
        KidneyStoneDetectionSystemLabel  matlab.ui.control.Label
        TextArea             matlab.ui.control.TextArea
        ResultTextAreaLabel  matlab.ui.control.Label
        RunButton            matlab.ui.control.Button
        SelectImageButton    matlab.ui.control.Button
        UIAxes6              matlab.ui.control.UIAxes
        UIAxes9              matlab.ui.control.UIAxes
        UIAxes8              matlab.ui.control.UIAxes
        UIAxes7              matlab.ui.control.UIAxes
        UIAxes5              matlab.ui.control.UIAxes
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        axes1                matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImage_Callback(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.gif','All Image Files'});
            if filename ~= 0
                % Read the selected image
                image = imread(fullfile(pathname, filename));
            
                % Display the selected image in the first axes
                imshow(image, 'Parent', app.axes1);
            end
        end

        % Button pushed function: RunButton
        function runCodeButtonPushed(app, event)
                % Get the input image from the axes component
    image = get(app.axes1, 'Children');
    image = get(image, 'CData');
    
    % Convert the image to grayscale
    b = rgb2gray(image);
    
    % Apply thresholding to the image
    c = b > 20;
    imshow(c, 'Parent', app.UIAxes3);
    d = imfill(c, 'holes');
    imshow(d, 'Parent', app.UIAxes4);
    e = bwareaopen(d, 1000);
    imshow(c, 'Parent', app.UIAxes5);
    PreprocessedImage = uint8(double(image).*repmat(e, [1 1 3]));
    PreprocessedImage = imadjust(PreprocessedImage, [0.3 0.7], []) + 50;
    imshow(PreprocessedImage, 'Parent', app.UIAxes6);
    % Apply median filtering
    uo = rgb2gray(PreprocessedImage);
    mo = medfilt2(uo, [5 5]);
    imshow(mo, 'Parent', app.UIAxes7);
    % Threshold the image again
    po = mo > 250;
    imshow(po, 'Parent', app.UIAxes8);
    % Get the dimensions of the image
    [r, c] = size(po);
    
    % Create the line around the stone
    x1 = r/2;
    y1 = c/3;
    row = [x1 x1+200 x1+200 x1];
    col = [y1 y1 y1+40 y1+40];
    BW = roipoly(po, row, col);
    k = po.*double(BW);
    M = bwareaopen(k, 4);
    imshow(M, 'Parent', app.UIAxes9);
    [L, num] = bwlabel(M);
    
    % Initialize arrays to store the information of stones
    stoneSizes = [];
    rectPositions = [];
if(num >= 1)
    % Loop through all connected components (stones)
    for i = 1:num
        % Calculate the size of the stone
        [r, c] = find(L == i);
        stoneArea = sum(sum(L == i)) * (1^2); % in mm^2
        stoneSize = sqrt(stoneArea); % in mm
        stoneSizes = [stoneSizes, stoneSize];

        % Draw a rectangle around the stone
        rectPos = [min(c), min(r), max(c) - min(c) + 1, max(r) - min(r) + 1];
        rectPositions = [rectPositions; rectPos];
    end

    % Plot the image with rectangles around the stones
    imshow(image, 'Parent', app.UIAxes2);
    hold on
    for i = 1:num
        rectangle('Position', rectPositions(i,:), 'EdgeColor', 'r', 'LineWidth', 2,  'Parent', app.UIAxes2);
    end
    hold off
    
    % Display the sizes of stones in the text box
    stoneSizesString = sprintf('%.2f, ', stoneSizes);
    app.TextArea.Value = ['Stone(s) Detected.',newline, 'Sizes of stones: ', stoneSizesString(1:end-2), ' mm'];
else
    % Plot the original image if no stone is detected
    imshow(image, 'Parent', app.UIAxes2);
    app.TextArea.Value = 'No Stone is detected';
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes1 = uiaxes(app.UIFigure);
            title(app.axes1, 'Title')
            xlabel(app.axes1, 'X')
            ylabel(app.axes1, 'Y')
            zlabel(app.axes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.UIFigure);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImage_Callback, true);
            app.SelectImageButton.Text = 'Select Image';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runCodeButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.TextArea = uitextarea(app.UIFigure);
            app.KidneyStoneDetectionSystemLabel = uilabel(app.UIFigure);
            app.KidneyStoneDetectionSystemLabel.Text = 'Kidney Stone Detection System';
            app.ThresholdingLabel = uilabel(app.UIFigure);
            app.ThresholdingLabel.Text = 'Thresholding';
            app.imfillLabel = uilabel(app.UIFigure);
            app.imfillLabel.Text = 'imfill';
            app.bwareaopenLabel = uilabel(app.UIFigure);
            app.bwareaopenLabel.Text = 'bwareaopen';
            app.PreprocessedLabel = uilabel(app.UIFigure);
            app.PreprocessedLabel.Text = 'Pre-processed';
            app.MedianfilterLabel = uilabel(app.UIFigure);
            app.MedianfilterLabel.Text = 'Median filter';
            app.ThresholdingLabel_6 = uilabel(app.UIFigure);
            app.ThresholdingLabel_6.Text = 'Thresholding';
            app.bwareaopenLabel_2 = uilabel(app.UIFigure);
            app.bwareaopenLabel_2.Text = 'bwareaopen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end